classdef rateConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SamplingRateConverterUIFigure  matlab.ui.Figure
        MethodtypeDropDown             matlab.ui.control.DropDown
        MethodtypeDropDownLabel        matlab.ui.control.Label
        NoteItmaytakesometimetoprocesstheconversionLabel  matlab.ui.control.Label
        AudioFilesDropDown             matlab.ui.control.DropDown
        AudioFilesDropDownLabel        matlab.ui.control.Label
        ConvertPlayButton              matlab.ui.control.Button
        DesiredSamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        DesiredSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        OriginalSamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        OriginalSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingRateConverterLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertPlayButton
        function resample(app, event)

            % Read the audio file
            [x, ~] = audioread(app.AudioFilesDropDown.Value);
            format rational
            desiredFreq = app.DesiredSamplingFrequencyHzEditField.Value;
            [L, M] = rat(desiredFreq./44100);
            
            switch app.MethodtypeDropDown.Value
                case app.MethodtypeDropDown.Items(1)
                    rateconverter(x, L, M, desiredFreq);
                case app.MethodtypeDropDown.Items(2)
                    i = interpolate(x, L);
                    d = downsample(i, M);
                    sound(d, app.DesiredSamplingFrequencyHzEditField.Value);
            end
            
%             if app.MethodtypeDropDown.Value == ''
%             
%             i = interpolate(x, L);
%             d = downsamp(i, 441);
%             sound(d, app.DesiredSamplingFrequencyHzEditField.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SamplingRateConverterUIFigure = uifigure('Visible', 'off');
            app.SamplingRateConverterUIFigure.Name = 'Sampling Rate Converter';
            app.SamplingRateConverterLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.SamplingRateConverterLabel.Text = 'Sampling Rate Converter';
            app.OriginalSamplingFrequencyHzEditFieldLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.OriginalSamplingFrequencyHzEditFieldLabel.Text = 'Original Sampling Frequency (Hz)';
            app.OriginalSamplingFrequencyHzEditField = uieditfield(app.SamplingRateConverterUIFigure, 'numeric');
            app.OriginalSamplingFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.OriginalSamplingFrequencyHzEditField.Editable = 'off';
            app.OriginalSamplingFrequencyHzEditField.Enable = 'off';
            app.OriginalSamplingFrequencyHzEditField.Value = 44100;
            app.DesiredSamplingFrequencyHzEditFieldLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.DesiredSamplingFrequencyHzEditFieldLabel.Text = 'Desired Sampling Frequency (Hz)';
            app.DesiredSamplingFrequencyHzEditField = uieditfield(app.SamplingRateConverterUIFigure, 'numeric');
            app.DesiredSamplingFrequencyHzEditField.Limits = [1000 1000000];
            app.DesiredSamplingFrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.DesiredSamplingFrequencyHzEditField.Value = 22000;
            app.ConvertPlayButton = uibutton(app.SamplingRateConverterUIFigure, 'push');
            app.ConvertPlayButton.ButtonPushedFcn = createCallbackFcn(app, @resample, true);
            app.ConvertPlayButton.Text = 'Convert - Play';
            app.AudioFilesDropDownLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.AudioFilesDropDownLabel.Text = 'Audio Files';
            app.AudioFilesDropDown = uidropdown(app.SamplingRateConverterUIFigure);
            app.AudioFilesDropDown.Items = {'DSP.wav', 'drums.wav'};
            app.AudioFilesDropDown.Value = 'DSP.wav';
            app.NoteItmaytakesometimetoprocesstheconversionLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.NoteItmaytakesometimetoprocesstheconversionLabel.Text = 'Note: It may take some time to process the conversion.';
            app.MethodtypeDropDownLabel = uilabel(app.SamplingRateConverterUIFigure);
            app.MethodtypeDropDownLabel.Text = 'Method type';
            app.MethodtypeDropDown = uidropdown(app.SamplingRateConverterUIFigure);
            app.MethodtypeDropDown.Items = {'IIR Filter (Custom)', 'Using FFT & IFFT'};
            app.MethodtypeDropDown.Value = 'IIR Filter (Custom)';
            % Show the figure after all components are created
            app.SamplingRateConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rateConverter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SamplingRateConverterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SamplingRateConverterUIFigure)
        end
    end
end